classdef proyeccion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        LeftPanel   matlab.ui.container.Panel
        UIAxes      matlab.ui.control.UIAxes
        RightPanel  matlab.ui.container.Panel
        UIAxes2     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cla (app.UIAxes2,'reset')
            hold (app.UIAxes2,'on')
            [X Y Z] = sphere;
            surf(app.UIAxes2,X,Y,Z);
            xlabel(app.UIAxes2,'x');
            ylabel(app.UIAxes2,'y');
            zlabel(app.UIAxes2,'z');
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            temp = app.UIAxes.CurrentPoint; % Returns 2x3 array of points
            if temp(1,1) <= 5 && temp(1,1) >= -5
                if temp(1,2) <= 5 && temp(1,2) >= -5
                    loc = [temp(1,1) temp(1,2)]; % Gets the (x,y) coordinates
                    disp(loc);
                end
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {310, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.LeftPanel);
            title(app.UIAxes, {'Plano complejo'; ''})
            xlabel(app.UIAxes, 'Re(z)')
            ylabel(app.UIAxes, 'Im(z)')
            app.UIAxes.XLim = [-5 5];
            app.UIAxes.YLim = [-5 5];
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.XTick = [-4 -3 -2 -1 0 1 2 3 4 5];
            app.UIAxes.XTickLabel = {'-4'; '-3'; '-2'; '-1'; ''; '1'; '2'; '3'; '4'; '5'};
            app.UIAxes.YAxisLocation = 'origin';
            app.UIAxes.YTick = [-4 -3 -2 -1 0 1 2 3 4];
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proyeccion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end